classdef StimCtrlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StimCtrlUIFigure              matlab.ui.Figure
        OpenBatchProcessorMenu        matlab.ui.container.Menu
        BolusTriggercontrolPanel      matlab.ui.container.Panel
        TrgDurationEditField          matlab.ui.control.NumericEditField
        secondsEditFieldLabel         matlab.ui.control.Label
        TrgNumEditField               matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        DurationPhysioMonitorLabel    matlab.ui.control.Label
        NumberAuroraLabel             matlab.ui.control.Label
        sendcustomtriggerButton       matlab.ui.control.Button
        BOLUSButton                   matlab.ui.control.Button
        RunStimulationPanel           matlab.ui.container.Panel
        ABORTStimButton               matlab.ui.control.Button
        DelaysLabel_2                 matlab.ui.control.Label
        StimulationDelayEditField     matlab.ui.control.NumericEditField
        StimModeTabGroup              matlab.ui.container.TabGroup
        PulseTrainTab                 matlab.ui.container.Tab
        TrainFrequencyEditField       matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel_3   matlab.ui.control.Label
        TrainStimDurationEditField    matlab.ui.control.NumericEditField
        StimDurationsLabel            matlab.ui.control.Label
        TrainPulseWidthEditField      matlab.ui.control.NumericEditField
        PulseWidthmsEditFieldLabel_3  matlab.ui.control.Label
        TrainAmplitudeEditField       matlab.ui.control.NumericEditField
        AmplitudemAEditFieldLabel_3   matlab.ui.control.Label
        PulseBurstTab                 matlab.ui.container.Tab
        StimDurationsLabel_2          matlab.ui.control.Label
        BurstAmplitudeEditField       matlab.ui.control.NumericEditField
        BurstPulseWidthEditField      matlab.ui.control.NumericEditField
        PulseWidthmsEditFieldLabel_2  matlab.ui.control.Label
        AmplitudemAEditFieldLabel_2   matlab.ui.control.Label
        BurstStimDurationEditField    matlab.ui.control.NumericEditField
        BurstFrequencyEditField       matlab.ui.control.NumericEditField
        BurstPulseCountSpinner        matlab.ui.control.Spinner
        PulsesBurstSpinnerLabel       matlab.ui.control.Label
        BurstBurstFreqEditField       matlab.ui.control.NumericEditField
        BurstIntervalsLabel           matlab.ui.control.Label
        FrequencyHzLabel              matlab.ui.control.Label
        DelaysLabel                   matlab.ui.control.Label
        IntervalsLabel                matlab.ui.control.Label
        STIMULATEButton               matlab.ui.control.Button
        StimBolusDelayEditField       matlab.ui.control.NumericEditField
        StimBolusCheckBox             matlab.ui.control.CheckBox
        StimBolusLabel                matlab.ui.control.Label
        ProtocolIntervalEditField     matlab.ui.control.NumericEditField
        ProtocolRepeatsSpinner        matlab.ui.control.Spinner
        RepetitionsLabel              matlab.ui.control.Label
        RepeatProtocolLabel           matlab.ui.control.Label
        Channel3CheckBox              matlab.ui.control.CheckBox
        Channel2CheckBox              matlab.ui.control.CheckBox
        Channel1CheckBox              matlab.ui.control.CheckBox
        ActiveChannelsLabel           matlab.ui.control.Label
        DeviceSetupPanel              matlab.ui.container.Panel
        TriggerBoxPortDropDown        matlab.ui.control.DropDown
        TriggerBoxPortDropDownLabel   matlab.ui.control.Label
        RefreshSerialportsButton      matlab.ui.control.Button
        ConnectStimulatorButton       matlab.ui.control.Button
    end

    %% ========== TODOs ==============================================
    % catch exception if writing to serial port failes, e.g. if cable
    % disconnected. Issue UI warning!

    
    % PROPERTIES ==========================================================
    
    properties (Access = public)
        nrisDataTrg = [] % 2-elemnt with time and trigger#: [secondsOfDay, trgNumber]
        logPath = fullfile(fileparts(mfilename("fullpath")),'logs');
        logFile
        lsloutlet

        stimObj = struct('startStim',@()fprintf('%s DUMMY: stimulation \n',datestr(datetime,'HH:MM:SS.FFF')),...
                         'sentDataToDevice',@(varargin)fprintf('%s DUMMY: stim parameters setup...\n',datestr(datetime,'HH:MM:SS.FFF')));

        lslPath = fullfile(fileparts(mfilename("fullpath")),'liblsl-Matlab') % path to the lsl matlab interface
        mcsPath = fileparts(mfilename("fullpath")) % path to the +mcs package

        batchFilePath = fileparts(mfilename("fullpath"));
    end
    
    properties (SetAccess = private)
        bolusPrepDelay = 10; % delay between 1st trigger and actual bolus release in seconds
        bolusTrgNums = [49 50 51];
        bolusTrgDuration = 15; % duration for setting injector-trigger to high. CAVE: if too short injection will be aborted?!

        scomObj
        physioTrgTimer % Description
        bolusAndPhysTrgTimer

        stimulationTimer
        stimProtocolTimer
        stimulationState_ = 'disconnected';
        stimOnOffTrigger = [90 91];
        stimPhysioTrgOnOffDuration = [.5 1];
    end
    
    properties (Dependent)
        stimulationState        
        stimParameter
        protocolParameter
    end
    
    properties (Constant)
        % TriggerBox parameter
        iON  = 'a';
        iOFF = 'b';
        pON  = 'cx';
        pOFF = 'dy';
        PreferedCOMnamePatt = '.*Serielles USB-Gerät.*'; % regexp to match prefered COM port name
        SerialPortParam = {9600}; % Additional parameters passed to the serialport() function        

        % LSL-TRIGGER parameter
        OutTLabel = 'Trigger_out';
        OutTName = 'Trigger';

        % STIMULATOR parameter
        SETTLING_TIME = .04; % duration in ms between & after current change
        STIM_NAME = 'STG4004';

        CFGFILE = fullfile(fileparts(mfilename("fullpath")),'cfg.mat');
        SAVEPROPS = {'lslPath','mcsPath','stimParameter','protocolParameter','batchFilePath'}
    end
    
    
    % METHODS =============================================================
    
    %% SETTER, GETTER
    methods 
        function set.scomObj(app,val)
            try
               app.scomObj.delete();                
            catch ME                
            end
            app.scomObj = val;
        end
        
        % .................................................................

        function set.stimulationState(app,state)
            state = validatestring(state,{'stopped','running','error','disconnected'});
            app.STIMULATEButton.Enable = 'on';
            switch state
                case {'stopped' 'error'}
                    app.STIMULATEButton.Visible = 'on';
                    app.ABORTStimButton.Visible = 'off';
                case {'running'} 
                    app.STIMULATEButton.Visible = 'off';
                    app.ABORTStimButton.Visible = 'on';
                case {'disconnected'}
                    app.STIMULATEButton.Enable = 'off';
                    app.STIMULATEButton.Visible = 'on';
                    app.ABORTStimButton.Visible = 'off';
            end
            app.stimulationState_ = state;
        end

        function state = get.stimulationState(app)
            state = app.stimulationState_;
        end
%         function val = get.scomObj(app)
%             val = app.scomObj_;
%         end

        function P = get.stimParameter(app)
            P.train = getStimParam(app,'train');
            P.burst = getStimParam(app,'burst');
            switch app.StimModeTabGroup.SelectedTab
                case app.PulseTrainTab
                    P.mode = 'train';
                case app.PulseBurstTab
                    P.mode = 'burst';
                otherwise, error('This is a bug');
            end
        end
        function set.stimParameter(app,P)
            app.TrainAmplitudeEditField.Value     = P.train.Amplitude;
            app.TrainFrequencyEditField.Value     = P.train.Frequency;
            app.TrainPulseWidthEditField.Value    = P.train.PulseWidth;
            app.TrainStimDurationEditField.Value  = P.train.StimDuration;
            app.BurstAmplitudeEditField.Value     = P.burst.Amplitude;
            app.BurstFrequencyEditField.Value     = P.burst.Frequency;
            app.BurstPulseWidthEditField.Value    = P.burst.PulseWidth;
            app.BurstStimDurationEditField.Value  = P.burst.StimDuration;
            app.BurstPulseCountSpinner.Value      = P.burst.PulseCount;
            app.BurstBurstFreqEditField.Value     = P.burst.BurstFreq;
            switch P.mode
                case 'train', app.StimModeTabGroup.SelectedTab = app.PulseTrainTab;
                case 'burst', app.StimModeTabGroup.SelectedTab = app.PulseBurstTab;
                otherwise, warning('There was a bug spotted in the set method of the stimParameter property!');
            end
        end

        function P = get.protocolParameter(app)
            P = app.getProtocolParam();
        end
        function set.protocolParameter(app,P)
            app.ProtocolRepeatsSpinner.Value    = P.ProtocolRepeats;
            app.ProtocolIntervalEditField.Value = P.ProtocolInterval;
            app.StimBolusCheckBox.Value         = P.StimBolus;
            app.StimBolusDelayEditField.Value   = P.StimBolusDelay + app.bolusPrepDelay;
            app.StimulationDelayEditField.Value = P.StimulationDelay;
        end
    end


    %% ##################### PUBLIC, STATIC ###############################
    methods (Access = public, Static)
    
        function startTimerAt(tObj,tStart)
            try
                startat(tObj,tStart);
            catch ME
                if strcmpi(ME.identifier,'MATLAB:timer:startdelaynegative')
%                     disp('delay negative')
                    tObj.StartDelay = 0;
                    start(tObj);
                else
                    throwAsCaller(ME);
                end
            end
        end
        
        %% ================================================================

        function [fullnames, comNums] = getCOMports()
            if ispc
                cmd = 'powershell -command "Get-WmiObject Win32_pnpentity  -Filter """Caption LIKE ''%(COM%)%''""" | Select-Object -Property Name"';
                [s,r] = system(cmd);
                if s~=0
                    warning('StimCtrlGUI:getCOMports:systemCmdFailed','Failed to execute system command to retrieve com ports.')
                    [fullnames, comNums] = getCOMportsFallback();
                else
                    fullnames = regexp(r,'(?<=\n)[^\n]*\(COM\d+\)(?=\s*\n)','match');
                    comNums = regexp(fullnames,'(?<=\(COM)\d+','match','once');
                end
            else
                % retrieving full com port names for other OS no
                % implemented yet!
                [fullnames, comNums] = getCOMportsFallback();
            end
        end
        
        %% ================================================================
                
        function [fullnames, comNums] = getCOMportsFallback()
            fullnames = serialportlist("all");
            if ~iscell(fullnames)
                fullnames = cellstr(fullnames);
            end
            comNums = regexp(fullnames,'(?<=COM)\d+','match','once');
        end
        
        %% ================================================================
        
        function comName = getMatlabCOMname(sysComPort)
            comName = regexp(sysComPort,'COM\d+','match','once');
            if ~ismember(comName,serialportlist('all'))
                warning('StimCtrlGUI:serialPort:NameUnknown2Matlab', ...
                    'COM port ''%s'' seems to be unknown to Matlab. Creating SerialPortObject may fail!', ...
                    sysComPort);
            end
        end

        %% ================================================================

        function updateDropDown(dropdObj,newList,preferedItem)
            currentVal = dropdObj.Value;
            newValIdx = [];
            % try to maintain previous value:
            if ~isempty(currentVal)
                newValIdx = find(strcmp(newList,currentVal));
            end
            % try to set prefered value
            if isempty(newValIdx) && nargin>2
                m = regexp(newList,preferedItem,'once');
                newValIdx = find(~cellfun(@isempty,m),1);
            end
            % if new value still empty and list not empty, set to 1
            if isempty(newValIdx) && ~isempty(newList)
                newValIdx = 1;
            end
            dropdObj.Items = newList;
            if ~isempty(newValIdx)
                dropdObj.Value = newList(newValIdx);
            end
        end

        %% ================================================================

        function r = otherAppRunning()
            appFigs = findall(groot,'Type','figure','-and','-property','RunningAppInstance');
            isStimCtrlApp = @(x)startsWith(class(x.RunningAppInstance), 'StimCtrl','IgnoreCase',true);
            nonStimCtrl = ~arrayfun(isStimCtrlApp,appFigs);
            r = any(nonStimCtrl);
        end

    end


    %% ##################### PRIVATE ######################################
    methods (Access = private)
        

        function setNSPdataTrg(app,value)
            % OBSOLETE
            % sets object property with current time and trg value
            value = fix(value);
            t = rem(now,1)*24*60*60;
            app.nrisDataTrg = [app.nrisDataTrg; [t, value]];
        end
        % .................................................................
        
        function sendLSLtrg(app,val)
            app.lsloutlet.push_sample(val);
        end
        % .................................................................
        
        function sendNIRStrg_(app, value)
            sendLSLtrg(app,value)
            % workaround for lsl-induced aurora crash:
%             setNSPdataTrg(app,value);
        end
        % .................................................................
        
        
        function physioTrgTimerInit(app,duration)
%             app.physioTrgTimer.UserData.startedAt = datetime;% use current time to get defined duration
            app.physioTrgTimer.UserData.COMlatency = write2com(app,app.pON);
%             app.physioTrgTimer.UserData.duration = duration;
            app.startTimerAt(app.physioTrgTimer, datetime+seconds(duration));
        end
        % .................................................................
        function physioTrgTimerFnc(app,tObj,event)
%             stopAt = tObj.UserData.startedAt + seconds(tObj.UserData.duration);
%             delay = stopAt-datetime-seconds(tObj.UserData.COMlatency);
%             pause(seconds(delay));
            l = write2com(app,app.pOFF);
        end
        % .................................................................
        function physioTrgTimerErr(app,tObj,event)
            msg = {'There was an error while executing the physio-trigger timer function:' ...
                event.Data.message};
            uialert(app.StimCtrlUIFigure, ...
                msg, ...
                'Trigger Error','Icon','error');
            app.writeLog('ERROR: %s',strjoin(msg));
        end
        
        % .................................................................
        
        
        function bolusAndPhysTrgTimerFnc(app,tObj,event)
            % struct('COMlatency',[],'delay',[],'startedAt',[],'state',[]));
            % warte wenn delay noch nicht erfüllt:
%             tExec = tObj.UserData.startedAt + seconds(tObj.UserData.delay);
%             tWait = tExec - datetime;
%             pause(seconds(tWait));
            
            tObj.UserData.startedAt = datetime; % (event.Data.time);  % <- CAVE nehmen wir die aktuelle Zeit oder die Zeit zu der der Callback in die queue kam?
            switch tObj.UserData.state
                case 'prep'
                    sendNIRStrg_(app,app.bolusTrgNums(1));
                    fprintf('%s (TCG): Bolus will be released in %.1f seconds...\n', ...
                                datestr(now,'yyyy-mm-dd HH:MM:SS'), app.bolusPrepDelay);
                    tObj.UserData.delay = app.bolusPrepDelay;
                case 'release'
                    sendNIRStrg_(app,app.bolusTrgNums(2));
                    lat = write2com(app,[app.iON app.pON]);
                    tObj.UserData.delay = max(0, app.bolusTrgDuration-lat);
                    writeLog(app,'Bolus released.');
                    fprintf('%s (TCG): Bolus released. Trigger will be low in %.1f seconds...\n', ...
                                datestr(now,'yyyy-mm-dd HH:MM:SS'), app.bolusTrgDuration-lat/2);
                case 'low'
                    sendNIRStrg_(app,app.bolusTrgNums(3));
                    lat = write2com(app,[app.iOFF app.pOFF]);
                    writeLog(app,'Bolus trigger low.');
                otherwise, warning('serious bug');
            end
        end
        % .................................................................
        function bolusAndPhysTrgTimerStopFnc(app,tObj,event)
            switch tObj.UserData.state
                case 'prep'
                    tObj.UserData.state = 'release';
                    app.startTimerAt(tObj, tObj.UserData.startedAt + seconds(tObj.UserData.delay)); %- .01
                case 'release'
                    tObj.UserData.state = 'low';
                    app.startTimerAt(tObj, tObj.UserData.startedAt + seconds(tObj.UserData.delay)); %- .01
                case 'low'
                otherwise, warning('serious bug');
            end
        end
        % .................................................................
        function bolusAndPhysTrgTimerErr(app,tObj,event)
            msg = {'There was an error while executing the bolus timer function:' ...
                event.Data.message};
            uialert(app.StimCtrlUIFigure, ...
                msg, ...
                'Bolus Error','Icon','error');
            app.writeLog('ERROR: %s',strjoin(msg));
        end
        
                
        % ....................stimulation Timer............................
       
        function stimTimerStartFnc(app,tObj,event)
            tObj.UserData.Intervals = NaN(1,tObj.TasksToExecute-1);
            app.sendNIRSandPhysioTrg(app.stimOnOffTrigger(1),app.stimPhysioTrgOnOffDuration(1));
            app.writeLog('StimTimer started, 1st execution in %.3gs...',app.stimPhysioTrgOnOffDuration(1));
        end

        % .................................................................

        function stimTimerFnc(app,tObj,event)
            app.stimObj.startStim();
            nInt = tObj.TasksExecuted-1;
            if nInt>0
                tObj.UserData.Intervals(nInt) = tObj.InstantPeriod;
            end
            if tObj.UserData.StimBolus && ...
                    tObj.UserData.BolusPending && ....
                    isfinite(tObj.UserData.BolusDateTime)
                if datetime>tObj.UserData.BolusDateTime
                    app.sendBolusAndPhysioTrg(0);
                    tObj.UserData.BolusPending = false;
                end
            end
        end

        % .................................................................

        function stimTimerStopFnc(app,tObj,event)

            % check if we need to repeat the protocol
            if tObj.UserData.ProtocolRepeats > tObj.UserData.ProtocolCounts
                delay = tObj.UserData.ProtocolInterval - app.stimPhysioTrgOnOffDuration(1);
                app.startTimerAt(app.stimProtocolTimer, datetime+seconds(delay));

            else
                app.stimulationState = 'stopped';

                % schedule Bolus if requested
                if tObj.UserData.BolusPending
                    app.sendBolusAndPhysioTrg(seconds(tObj.UserData.BolusDateTime-datetime));
                end
            end

            % Send triggers to NIRS and Physio
            app.sendNIRSandPhysioTrg(app.stimOnOffTrigger(2),app.stimPhysioTrgOnOffDuration(2));

            % write log with timings
            app.writeLog('Stimulation stopped. Pattern intervals (s): %s',num2str(tObj.UserData.Intervals,' %.6f'));
        end

        % .................................................................

        function stimTimerErrFnc(app,tObj,event)
            app.stimulationState = 'error';
            msg = {'There was an error while executing the stimulation timer function:' ...
                event.Data.message};
            uialert(app.StimCtrlUIFigure, ...
                msg, ...
                'Stimulation Error','Icon','error');
            app.writeLog('ERROR: %s',strjoin(msg));
        end


        % .................... protocol Timer .............................
                
        function protocolTimerStartFnc(app,tObj,event)
        end

        % .................................................................
        
        function protocolTimerFnc(app,tObj,event)
            app.stimulationTimer.UserData.ProtocolCounts = app.stimulationTimer.UserData.ProtocolCounts + 1;
            app.writeLog('Starting protocol iteration #%02d',app.stimulationTimer.UserData.ProtocolCounts)
%             start stimTimer
            app.stimulationTimer.StartDelay = app.stimPhysioTrgOnOffDuration(1); % wait until physio trigger is low again to avoid race conditions
            app.stimulationTimer.start();
        end

        % .................................................................
        
        function protocolTimerStopFnc(app,tObj,event)
%             write log with parameters?
        end

        % .................................................................
        
        function protocolTimerErrFnc(app,tObj,event)
            msg = {'There was an error while executing the stimulation protocol timer function:' ...
                event.Data.message};
            uialert(app.StimCtrlUIFigure, ...
                msg, ...
                'Protocol Error','Icon','error');
            app.writeLog('ERROR: %s',strjoin(msg));
            app.stimulationState = 'error';
        end


        % .................................................................
        
        
        function P = setupStimulation(app)
            % set stim parameter from GUI to timer objects
            SP = app.stimParameter;
            st = app.SETTLING_TIME;

            switch SP.mode
                case 'train'
                    P = SP.train;
                    amplitudes_mA = [0 -P.Amplitude 0 P.Amplitude 0]; %mV/uA 
                    durations_ms = [st P.PulseWidth st P.PulseWidth st]; %duration in ms

                    stimRate = P.Frequency;
                    addLog = '';

                case 'burst'
                    P = SP.burst;

                    if P.PulseCount/P.Frequency > 1/P.BurstFreq
                        res = uiconfirm(app.StimCtrlUIFigure, ...
                            {'Burst frequency is too high for given burst length.' 'Decrease Pulses/Burst or Burst Frequency or increase Frequency!'}, ...
                            'Stimulation parameter warning', 'Icon', 'error',...
                            'Options',{'Cancel','Continue anyway'}, 'CancelOption',1, 'DefaultOption',1);
                        if ~strcmpi(res,'Continue anyway')
                            throw(MException('StimCtrlGUI:stimSetupCanceled:burstTooLong','Stimulation canceled.'));
                        end
                    end

                    basePatt = [-P.Amplitude 0 P.Amplitude 0];
                    amplitudes_mA = [0 repmat(basePatt, 1, P.PulseCount)];

                    pulseInterv_ms = 1e3 / P.Frequency;
                    pulsePause_ms = pulseInterv_ms - 2*P.PulseWidth - st;

                    basePatt = [P.PulseWidth st P.PulseWidth pulsePause_ms];
                    durations_ms = [st repmat(basePatt, 1, P.PulseCount)];
                    durations_ms(end) = st;

%                     burstDuration = P.PulseCount / P.Frequency - pulsePause_ms/1e3; % == (sum(durations_ms)-2*st)/1e3
                    stimRate = P.BurstFreq; % 1/(P.Interval+burstDuration); % Interval = pause between bursts

                    addLog = sprintf('\tBURST_FREQ = %.2fHz\tBURST_PULSECNT = %d',P.BurstFreq,P.PulseCount);
                    
                otherwise, error('This is a bug!');
            end

            amplitudes_muA = amplitudes_mA*1000;
            durations_s = durations_ms/1000;

            pt1 = mcs.stg.pulse_train.fromAmpDurationArrays(...
               amplitudes_muA,durations_s,'output_type','current'); %mV/uA 
            
            %Send the pattern to channels
            chn = app.getActiveChn();
            app.stimObj.sentDataToDevice(chn,repmat({pt1},1,numel(chn)));
                        
            stimCnt = ceil(P.StimDuration * stimRate);
            app.stimulationTimer.TasksToExecute = stimCnt;
            app.stimulationTimer.Period = 1/stimRate;

            app.writeLog('Stimulation parameters setup:\tMODE = %s\tAMP = %smA\tDURATION_PATTERN = %sms\tSTIM_DURATION = %.1fs\tCOUNT = %d\tPULSE_FREQ = %.2fHz\tCHANNELS =%s%s', ...
                SP.mode, num2str(amplitudes_mA,' %.1f'), num2str(durations_ms,' %.2f'), P.StimDuration, stimCnt, P.Frequency, num2str(chn,' %d'), addLog)
        end

        % .................................................................

        function P = setupStimProtcol(app)
            P = app.getProtocolParam();
            P.ProtocolCounts = 0;

            if P.StimBolus && P.StimulationDelay < -P.StimBolusDelay
                uialert(app.StimCtrlUIFigure,'Stimulation delay and bolus injection time are incompatible!','Incompatible parameters!');
                error('Stimulation delay and bolus injection time are incompatible!');
            end
            app.stimulationTimer.UserData = P;
            app.stimulationTimer.UserData.Intervals = NaN;

            app.writeLog('Stimulation protcol setup:\tREPETITIONS = %d\tINTERVAL = %.1fs\tBOLUS = %d\tBOLUSDELAY = %.1fs', ...
                                                    P.ProtocolRepeats, P.ProtocolInterval,  P.StimBolus, P.StimBolusDelay);
        end


        % .................................................................
        

        % .................................................................
        
        function updateCOMportDropDown(app,newPortList)
            app.updateDropDown(app.TriggerBoxPortDropDown,newPortList,app.PreferedCOMnamePatt);
        end
    end


    %% ##################### PUBLIC  ######################################
    methods (Access = public)

        function runStimProtocol(app)
            app.checkExclusiveMLinstance();

            SP = app.setupStimulation();
            PP = app.setupStimProtcol();
            if PP.StimBolus %&& (PP.StimBolusDelay<=0 || PP.StimBolusDelay>SP.StimDuration)
                app.sendBolusAndPhysioTrg(PP.StimulationDelay+PP.StimBolusDelay);
                app.stimulationTimer.UserData.BolusDateTime = datetime + seconds(inf);
                app.stimulationTimer.UserData.BolusPending = false;
%             elseif PP.StimBolus
%                 app.stimulationTimer.UserData.BolusDateTime = datetime + ...
%                     seconds(PP.StimulationDelay + PP.StimBolusDelay);
%                 app.stimulationTimer.UserData.BolusPending = true;
            else
                app.stimulationTimer.UserData.BolusPending = false;
            end

            app.stimulationState = 'running';
            startTime = datetime + seconds(PP.StimulationDelay - app.stimPhysioTrgOnOffDuration(1));
            app.startTimerAt(app.stimProtocolTimer,startTime);
        end


        % .................................................................
        

        function abortStim(app)
            app.stimulationTimer.UserData.ProtocolCounts = inf;
            app.stimulationTimer.UserData.BolusPending = false;
            app.bolusAndPhysTrgTimer.UserData.state = 'low';
            th = [app.stimulationTimer,app.stimProtocolTimer,app.physioTrgTimer,app.bolusAndPhysTrgTimer];
            stop(th);
            app.write2com([app.iOFF,app.pOFF]);
            app.writeLog('Stimulation aborted.');
        end


        % .................................................................


        function sendNIRStrg(app, value)
            writeLog(app,'Triggering NIRS.')
            sendNIRStrg_(app,value);
        end

        % .................................................................

        function sendPhysioTrg(app,duration)
            writeLog(app,'Triggering Physio.');
%             sendPhysioTrg_(app,duration);
            app.physioTrgTimerInit(duration);
        end

        % .................................................................

        function sendNIRSandPhysioTrg(app,value,duration)
            writeLog(app,'Triggering NIRS and Physio.');
            sendNIRStrg_(app,value);
%             sendPhysioTrg_(app,duration);
            app.physioTrgTimerInit(duration);
        end
        % .................................................................
        
        function sendBolusAndPhysioTrg(app,delay)
%             S = dbstack; % DEBUG
%             fprintf('%s: Bolus init from %s\n',datestr(datetime,'HH:MM:SS.FFF'),S(2).name);

            writeLog(app,'Triggering bolus: NIRS data, physio, injector.');
            app.bolusAndPhysTrgTimer.UserData.startedAt = datetime;
            app.bolusAndPhysTrgTimer.UserData.delay = delay;
            app.bolusAndPhysTrgTimer.UserData.state = 'prep';
            app.startTimerAt(app.bolusAndPhysTrgTimer, datetime+seconds(delay)); %-.01
%             sendBolusAndPhysioTrg_(app);
        end

        % .................................................................
        
        function latency = write2com(app,buf)
%             latency = 0;
% %             warning('!!! Triggerbox DISABELED !!!')
%             fprintf('%s - %s\n',datestr(now(),'yyyy-mm-dd HH:MM:SS'),buf);
%             return; % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<< DEBUG<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
            
            if isempty(app.scomObj), error('Serial port for TriggerBox has not been set up!'); end
            app.scomObj.flush();
            t = tic;
            write(app.scomObj,buf,'char'); %uint8
            latency = toc(t);
            while app.scomObj.NumBytesAvailable<1
                latency = toc(t);
                if latency > 2
                    errordlg('No receipt received!','Error writing to COM port','modal')
                    app.writeLog('ERROR: No receipt from trigger box received within 2 seconds.')
                    break;
                end
            end
            
            app.writeLog('Latency was %f seconds',latency);
%             AnzBytes=app.scomObj.NumBytesAvailable;
%             Data=read(app.scomObj,AnzBytes,'char')
        end

        % .................................................................
        
        function writeLog(app,msg,varargin)
            if ~isempty(varargin)
                msg = sprintf(msg,varargin{:});
            end
            fid = fopen(app.logFile,'a');
            fprintf(fid,'%s: %s\n',datestr(now,'yy-mm-dd HH:MM:SS.FFF'), msg);
            fclose(fid);
        end

        % .................................................................
                
        function [s,po]=createScomObj(app)
            portName = app.TriggerBoxPortDropDown.Value;
            if isempty(portName)
                s=1; po=[];
            else
                if ~isempty(app.scomObj) && isprop(app.scomObj,'Port') && ...
                        strcmp(app.scomObj.Port, app.getMatlabCOMname(portName))
                    % delete obj if obj exists and port name is the same
                    app.scomObj = [];
                end
                [s,ME,po] = app.testSerialPort(portName);
                if s~=0
                    warndlg({sprintf('Failed to create serial port object with COM port %s! Check/reestablish connection!',portName), ME.message}, ...
                        'Error using COM port');
                end
            end
            app.scomObj = po;
            if ~isempty(app.scomObj)
                if ~isempty(regexp(portName,app.PreferedCOMnamePatt,'once'))
                    app.TriggerBoxPortDropDown.BackgroundColor = [0.4980    0.9412    0.3843];
                else
                    app.TriggerBoxPortDropDown.BackgroundColor = [0.9490    0.9255    0.2667];
                end
            else
                app.TriggerBoxPortDropDown.BackgroundColor = [0.8588    0.3961    0.1882];
            end
        end

        % .................................................................

        function [s,ME,po] = testSerialPort(app,name)
            comName = app.getMatlabCOMname(name);
            ME = []; po = [];
            s=0;
%             try delete(app.SerialComObj); end
            try
                po = serialport(comName,app.SerialPortParam{:});
                if nargout<3, delete(po); end
            catch ME
                s=1;
            end
        end

        % .................................................................

        function actChn = getActiveChn(app)
            actChn = [];
            if app.Channel1CheckBox.Value,  actChn = 1;
            end
            if app.Channel2CheckBox.Value,  actChn = [actChn 2];
            end
            if app.Channel3CheckBox.Value,  actChn = [actChn 4];
            end
        end

        % .................................................................

        function setActiveChn(app,actChn)
            if ismember(1,actChn), app.Channel1CheckBox.Value = true;
            else,                  app.Channel1CheckBox.Value = false;
            end
            if ismember(2,actChn), app.Channel2CheckBox.Value = true;
            else,                  app.Channel2CheckBox.Value = false;
            end
            if any(ismember([3 4],actChn)), app.Channel3CheckBox.Value = true;
            else,                           app.Channel3CheckBox.Value = false;
            end
        end

        % .................................................................

        function initStimulator(app)
            try
                s = mcs.getStimulator(app.STIM_NAME);
    
                %Note: 'repeat_all', 0 sets all triggers                                                                                                                                                                                                                             to have infinite repeats (manual stim stopping required)
                %number gives repeats for all channels
                s.setupTrigger('linearize',true,'repeat_all',1); % 
                
                %Set all channels to current mode
                s.setCurrentMode();
                app.stimObj = s;
                app.ConnectStimulatorButton.BackgroundColor = [0.4980    0.9412    0.3843];

                app.stimulationState = 'stopped';

            catch ME
                uialert(app.StimCtrlUIFigure, ...
                    {'There was an error while initializing the stimulator:' ...
                     ME.message}, ...
                    'Error setting up stimulator', 'Icon','error');
                app.ConnectStimulatorButton.BackgroundColor = [0.8588    0.3961    0.1882];
                app.stimObj = [];
                app.stimulationState = 'disconnected';
                rethrow(ME);
            end
        end

        % .................................................................

        function P = getStimParam(app,mode)
            % returns struct with fields 'Amplitude' [mA], 'Frequency',
            % 'PulseWidth', 'StimDuration' and for mode='burst'
            % additionally: 'PulseCount' and 'Interval'
            mode = validatestring(mode,{'train','burst'});
            switch mode
                case 'train'
                    P.Amplitude     = app.TrainAmplitudeEditField.Value;
                    P.Frequency     = app.TrainFrequencyEditField.Value;
                    P.PulseWidth    = app.TrainPulseWidthEditField.Value;
                    P.StimDuration  = app.TrainStimDurationEditField.Value;
                case 'burst'
                    P.Amplitude     = app.BurstAmplitudeEditField.Value;
                    P.Frequency     = app.BurstFrequencyEditField.Value;
                    P.PulseWidth    = app.BurstPulseWidthEditField.Value;
                    P.StimDuration  = app.BurstStimDurationEditField.Value;
                    P.PulseCount    = app.BurstPulseCountSpinner.Value;
                    P.BurstFreq     = app.BurstBurstFreqEditField.Value;
                otherwise, error('This is a bug.')
            end
        end

        % .................................................................

        function P = getProtocolParam(app)
            P.ProtocolRepeats   = app.ProtocolRepeatsSpinner.Value;
            P.ProtocolInterval  = app.ProtocolIntervalEditField.Value;
            P.StimBolus         = app.StimBolusCheckBox.Value;
            P.StimBolusDelay    = app.StimBolusDelayEditField.Value - app.bolusPrepDelay;
            P.StimulationDelay  = app.StimulationDelayEditField.Value;
        end

        % .................................................................

        function checkExclusiveMLinstance(app)
            if app.otherAppRunning
                uialert(app.StimCtrlUIFigure, {'There seems to be another app running in this instance of Matlab that may affect accuracy of the stimulation timer.' ...
                    'Run this app in a separate instance of matlab to maximize precision of pulse intervals during stimulation!'}, ...
                    'Exclusive Matlab instance highly recommended!')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init_gui(app)
            appPth = fileparts(mfilename('fullpath'));
            addpath(appPth);
            app.RefreshSerialportsButton.Icon = fullfile(appPth,'refresh.png');

            app.checkExclusiveMLinstance();

            % -------------------    load config    ------------------
            try
                cfg = load(app.CFGFILE);
                vars = fieldnames(cfg);
                for k = 1:numel(vars)
                    try app.(vars{k}) = cfg.(vars{k});
                    catch ME
                        warning('Failed to assign property ''%s'' from config file because\n%s',vars{k},ME.message);
                    end
                end
            catch ME
                warning(ME.identifier,'Failed to load properties from file because\n%s',ME.message);
            end


            % ---- CHECK FOR +mcs STIMULATOR INTERFACE PACKAGE -------
            addpath(app.mcsPath);
            try
                mcs.getStimulator();
            catch ME
                if strcmpi(ME.identifier,'MATLAB:undefinedVarOrClass')
                    uialert(app.StimCtrlUIFigure, ...
                        {'Please add path containing the +mcs folder to the matlab path.' ...
                         'Download here: <a href=''https://github.com/JimHokanson/multichannel_systems_stg_matlab''> https://github.com/JimHokanson/multichannel_systems_stg_matlab</a>'}, ...
                        'mcs package not found','Icon','warning','Interpreter','html');
                end
            end


            % ---- SET UP LSL OUTLET ---------------------------------
            if isdeployed
                lslPth = fileparts(fileparts(appPth));
                disp(['loading lsl_lib from ' lslPth])
%                 f = dir(fullfile(filepath,'liblsl-Matlab','bin'));
%                 char({f.name})
                lslib = lsl_loadlib_(fullfile(lslPth,'liblsl-Matlab','bin','lsl.dll'));
            else
                lslPth = app.lslPath;
                addpath(genpath(lslPth));
                if ~exist('lsl_loadlib','file')
                    d = uigetdir(userpath,'Select liblsl-Matlab directory');
                    if ~(ischar(d) || isStringScalar(d))  ||  ~exist(fullfile(d,'lsl_loadlib.m'),'file')
                        uialert(app.StimCtrlUIFigure, ...
                            {'Please add the path containing the matlab lsl interface and all subdirectories to the matlab path and RESTART the app.' ...
                             'Download here: <a href=''https://github.com/labstreaminglayer/liblsl-Matlab''> https://github.com/labstreaminglayer/liblsl-Matlab</a>'}, ...
                            'lsl package not found','Icon','warning','Interpreter','html','CloseFcn',@(~,~)delete(app));
                        error('liblsl-Matlab not found!');
                    else
                        app.lslPath = d;
                        addpath(genpath(d));
                    end
                end
                lslib = lsl_loadlib();
            end
            OutTInfo = lsl_streaminfo(lslib, app.OutTName, app.OutTLabel, 1, 0, 'cf_int8', 'sdfwerr32432');
            app.lsloutlet = lsl_outlet(OutTInfo);
            if ~0, disp(['LSL outlet ''' app.OutTName ''' created.']); end

            
            % ------ SET UP COM PORT ---------------------------------
            app.updateCOMportDropDown(app.getCOMports);
            app.createScomObj();


            % ------ SET UP LogFile ---------------------------------
            if ~exist(app.logPath,'dir'), mkdir(app.logPath); end
            app.logFile = fullfile(app.logPath,sprintf('%s.txt',datestr(now,'yyyymmdd')));
            
            
            % ------ SET UP TimerObjects ------------------------------
            app.physioTrgTimer = timer(...
                "BusyMode",                "queue",...
                "Name",                    'physioTriggerTimer', ...
                "ExecutionMode",           "singleShot", ...
                ..."StartFcn",                @(x,y)app.physioTrgTimerInit(x,y),...
                "TimerFcn",                @(x,y)app.physioTrgTimerFnc(x,y), ...
                "ErrorFcn",                @(x,y)app.physioTrgTimerErr(x,y), ...
               "UserData",                struct('COMlatency',[],'duration',[],'startedAt',[]));
            app.bolusAndPhysTrgTimer = timer(...
                "BusyMode",                "queue",...
                "Name",                    'bolusAndPhysTrgTimer', ...
                "ExecutionMode",           "singleShot", ...
                "TimerFcn",                @(x,y)app.bolusAndPhysTrgTimerFnc(x,y), ...
                "StopFcn",                 @(x,y)app.bolusAndPhysTrgTimerStopFnc(x,y), ...
                "ErrorFcn",                @(x,y)app.bolusAndPhysTrgTimerErr(x,y), ...
                "UserData",                struct('COMlatency',[],'delay',[],'startedAt',[],'state',[]));
            app.stimulationTimer = timer(...
                "BusyMode",                "queue",...
                "Name",                    'stimulationTimer', ...
                "ExecutionMode",           "fixedRate", ...
                "TimerFcn",                @(x,y)app.stimTimerFnc(x,y), ...
                "StartFcn",                @(x,y)app.stimTimerStartFnc(x,y), ...
                "StopFcn",                 @(x,y)app.stimTimerStopFnc(x,y), ...
                "ErrorFcn",                @(x,y)app.stimTimerErrFnc(x,y), ...
                "UserData",                struct('ProtocolRepeats',1, 'ProtocolCounts',0, ...
                                                  'StimBolus',true, 'state',[], 'BolusDateTime',inf, ...
                                                  'BolusPending',false, 'Intervals',NaN));
            app.stimProtocolTimer = timer(...
                "BusyMode",                "queue",...
                "Name",                    'stimProtocolTimer', ...
                "ExecutionMode",           "singleShot", ...
                "TimerFcn",                @(x,y)app.protocolTimerFnc(x,y), ...
                "StartFcn",                @(x,y)app.protocolTimerStartFnc(x,y), ...
                "StopFcn",                 @(x,y)app.protocolTimerStopFnc(x,y), ...
                "ErrorFcn",                @(x,y)app.protocolTimerErrFnc(x,y), ...
                "UserData",                struct('state','stopped'));
            

            % ------ ADAPT SOME GUI OBJECTS ----------------------------
%             app.StimBolusDelayEditField.Limits(1) = app.bolusPrepDelay;

        end

        % Button pushed function: BOLUSButton
        function bolus_init(app, event)
            answer = inputdlg({'Delay in seconds:'},'Trigger BOLUS',[1,35],{'0'});
            if isempty(answer)
                return;
            else
                tdelay = str2double(answer{1});
                if ~isempty(tdelay) && isfinite(tdelay)
                    fprintf('%s (TCG): Waiting for %d seconds before bolus init...\n', ...
                        datestr(now,'yyyy-mm-dd HH:MM:SS'), tdelay(1));
%                     pause(tdelay(1));
                else
                    warndlg('Failed to interpret entered delay! Bolus wont be initiated.');
                    return;
                end
            end
            sendBolusAndPhysioTrg(app,tdelay);
        end

        % Button pushed function: sendcustomtriggerButton
        function sendCustomTrg(app, event)
            nirsTrgNum = app.TrgNumEditField.Value;
            physTrgDur = app.TrgDurationEditField.Value;
            if nirsTrgNum>0 && physTrgDur>0
                msg = sprintf('Sending trigger %d to NIRS and setting Physio-Trigger to high for %.1f seconds.',nirsTrgNum,physTrgDur);
                fncHndl = @()app.sendNIRSandPhysioTrg(nirsTrgNum,physTrgDur);
            elseif nirsTrgNum>0
                msg = sprintf('Sending trigger %d to NIRS.',nirsTrgNum);
                fncHndl = @()app.sendNIRStrg(nirsTrgNum);
            elseif physTrgDur>0
                msg = sprintf('Setting Physio-Trigger to high for %.1f seconds.',physTrgDur);
                fncHndl = @()app.sendPhysioTrg(physTrgDur);
            else
                msgbox('No trigger to send.');
                return;
            end
            answer = uiconfirm(app.StimCtrlUIFigure, ...
                msg, 'Confirm custom trigger');
            if strcmpi(answer,'ok')
                fncHndl();
            end
        end

        % Close request function: StimCtrlUIFigure
        function figDestructor(app, event)
%             fclose(app.logfid)
            try app.SerialComObj.delete(); end
            th = [app.stimulationTimer,app.stimProtocolTimer,app.physioTrgTimer,app.bolusAndPhysTrgTimer];
            try delete(th); end

            try
                for k = 1:numel(app.SAVEPROPS)
                    var = app.SAVEPROPS{k};
                    cfg.(var) = app.(var);
                end
                save(app.CFGFILE,'-struct','cfg');
            catch ME
                warning(ME.identifier,'Failed to save properties to config file because\n%s',ME.message);
            end
            delete(app)

        end

        % Button pushed function: RefreshSerialportsButton
        function RefreshSerialportsButtonPushed(app, event)
            app.updateCOMportDropDown(app.getCOMports);
            app.createScomObj();
        end

        % Value changed function: TriggerBoxPortDropDown
        function TriggerBoxPortDropDownValueChanged(app, event)
            app.createScomObj();
        end

        % Button pushed function: ConnectStimulatorButton
        function ConnectStimulatorButtonPushed(app, event)
            app.initStimulator();
        end

        % Button pushed function: STIMULATEButton
        function STIMULATEButtonPushed(app, event)
            app.runStimProtocol();
        end

        % Button pushed function: ABORTStimButton
        function ABORTStimButtonPushed(app, event)
            resp = uiconfirm(app.StimCtrlUIFigure, ...
                'A you sure to abort stimulation?','Confirm stopping stimulation', ...
                'Options',{'yes','no'},'CancelOption',2,'DefaultOption',1);
            if ~strcmpi(resp,'yes'), return; end
            app.abortStim();
        end

        % Menu selected function: OpenBatchProcessorMenu
        function OpenBatchProcessorMenuSelected(app, event)
            StimCtrl_ProtocolBatch(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StimCtrlUIFigure = uifigure('Visible', 'off');
            app.StimCtrlUIFigure.Name = 'Stimulation Control';
            app.StimCtrlUIFigure.CloseRequestFcn = createCallbackFcn(app, @figDestructor, true);
            app.OpenBatchProcessorMenu = uimenu(app.StimCtrlUIFigure);
            app.OpenBatchProcessorMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenBatchProcessorMenuSelected, true);
            app.OpenBatchProcessorMenu.Text = 'Open Batch Processor...';
            app.DeviceSetupPanel = uipanel(app.StimCtrlUIFigure);
            app.DeviceSetupPanel.Title = 'Device Setup';
            app.ConnectStimulatorButton = uibutton(app.DeviceSetupPanel, 'push');
            app.ConnectStimulatorButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectStimulatorButtonPushed, true);
            app.ConnectStimulatorButton.Text = 'Connect Stimulator';
            app.RefreshSerialportsButton = uibutton(app.DeviceSetupPanel, 'push');
            app.RefreshSerialportsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshSerialportsButtonPushed, true);
            app.RefreshSerialportsButton.Tooltip = {'Refresh list of COM ports'};
            app.RefreshSerialportsButton.Text = '';
            app.TriggerBoxPortDropDownLabel = uilabel(app.DeviceSetupPanel);
            app.TriggerBoxPortDropDownLabel.Text = 'Trigger-Box Port:';
            app.TriggerBoxPortDropDown = uidropdown(app.DeviceSetupPanel);
            app.TriggerBoxPortDropDown.Items = {};
            app.TriggerBoxPortDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerBoxPortDropDownValueChanged, true);
            app.TriggerBoxPortDropDown.Tooltip = {'Choose COM port to which TriggerBox is connected to. See Device Manager (Geräte Manager), name should be ''Serielles USB-Geraet'''};
            app.TriggerBoxPortDropDown.Value = {};
            app.RunStimulationPanel = uipanel(app.StimCtrlUIFigure);
            app.RunStimulationPanel.Title = 'Run Stimulation';
            app.ActiveChannelsLabel = uilabel(app.RunStimulationPanel);
            app.ActiveChannelsLabel.Text = 'Active Channels:';
            app.Channel1CheckBox = uicheckbox(app.RunStimulationPanel);
            app.Channel1CheckBox.Text = 'Channel 1';
            app.Channel1CheckBox.Value = true;
            app.Channel2CheckBox = uicheckbox(app.RunStimulationPanel);
            app.Channel2CheckBox.Text = 'Channel 2';
            app.Channel2CheckBox.Value = true;
            app.Channel3CheckBox = uicheckbox(app.RunStimulationPanel);
            app.Channel3CheckBox.Text = 'Channel 3';
            app.Channel3CheckBox.Value = true;
            app.RepeatProtocolLabel = uilabel(app.RunStimulationPanel);
            app.RepeatProtocolLabel.Text = 'Repeat Protocol:';
            app.RepetitionsLabel = uilabel(app.RunStimulationPanel);
            app.RepetitionsLabel.Text = 'Repetitions:';
            app.ProtocolRepeatsSpinner = uispinner(app.RunStimulationPanel);
            app.ProtocolRepeatsSpinner.Limits = [1 Inf];
            app.ProtocolRepeatsSpinner.ValueDisplayFormat = '%.0f';
            app.ProtocolRepeatsSpinner.Value = 1;
            app.ProtocolIntervalEditField = uieditfield(app.RunStimulationPanel, 'numeric');
            app.ProtocolIntervalEditField.Limits = [0 Inf];
            app.ProtocolIntervalEditField.Tooltip = {'Pause between end of stimulation and onset of next stimulation'};
            app.StimBolusLabel = uilabel(app.RunStimulationPanel);
            app.StimBolusLabel.Text = 'Schedule Bolus:';
            app.StimBolusCheckBox = uicheckbox(app.RunStimulationPanel);
            app.StimBolusCheckBox.Text = '';
            app.StimBolusCheckBox.Value = true;
            app.StimBolusDelayEditField = uieditfield(app.RunStimulationPanel, 'numeric');
            app.StimBolusDelayEditField.Tooltip = {'Delay after start of stimulation until bolus is released. Negative value results in injection before stimulation.'; 'The minimum is defined by the bolus preparation delay.'};
            app.StimBolusDelayEditField.Value = -45;
            app.STIMULATEButton = uibutton(app.RunStimulationPanel, 'push');
            app.STIMULATEButton.ButtonPushedFcn = createCallbackFcn(app, @STIMULATEButtonPushed, true);
            app.STIMULATEButton.Text = 'STIMULATE';
            app.IntervalsLabel = uilabel(app.RunStimulationPanel);
            app.IntervalsLabel.Text = 'Pause (s):';
            app.DelaysLabel = uilabel(app.RunStimulationPanel);
            app.DelaysLabel.Text = 'Injection time relative to stimulation onset (s):';
            app.StimModeTabGroup = uitabgroup(app.RunStimulationPanel);
            app.PulseTrainTab = uitab(app.StimModeTabGroup);
            app.PulseTrainTab.Title = 'Pulse Train';
            app.PulseTrainTab.Tag = 'train';
            app.AmplitudemAEditFieldLabel_3 = uilabel(app.PulseTrainTab);
            app.AmplitudemAEditFieldLabel_3.Text = 'Amplitude (mA):';
            app.TrainAmplitudeEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.TrainAmplitudeEditField.LowerLimitInclusive = 'off';
            app.TrainAmplitudeEditField.Limits = [0 Inf];
            app.TrainAmplitudeEditField.Tooltip = {'Current will be first set to -x and then to +x'};
            app.TrainAmplitudeEditField.Value = 6;
            app.PulseWidthmsEditFieldLabel_3 = uilabel(app.PulseTrainTab);
            app.PulseWidthmsEditFieldLabel_3.Text = 'Pulse Width (ms):';
            app.TrainPulseWidthEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.TrainPulseWidthEditField.LowerLimitInclusive = 'off';
            app.TrainPulseWidthEditField.Limits = [0 Inf];
            app.TrainPulseWidthEditField.Tooltip = {'Duration of negative and positive currents.'; '-> Overall pulse duration is twice this value.'};
            app.TrainPulseWidthEditField.Value = 1;
            app.StimDurationsLabel = uilabel(app.PulseTrainTab);
            app.StimDurationsLabel.Text = 'Stim. Duration (s):';
            app.TrainStimDurationEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.TrainStimDurationEditField.LowerLimitInclusive = 'off';
            app.TrainStimDurationEditField.Limits = [0 Inf];
            app.TrainStimDurationEditField.Tooltip = {'Duration of stimulation'};
            app.TrainStimDurationEditField.Value = 5;
            app.FrequencyHzEditFieldLabel_3 = uilabel(app.PulseTrainTab);
            app.FrequencyHzEditFieldLabel_3.Text = 'Frequency (Hz):';
            app.TrainFrequencyEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.TrainFrequencyEditField.LowerLimitInclusive = 'off';
            app.TrainFrequencyEditField.Limits = [0 Inf];
            app.TrainFrequencyEditField.Tooltip = {'Pulse rate'};
            app.TrainFrequencyEditField.Value = 10;
            app.PulseBurstTab = uitab(app.StimModeTabGroup);
            app.PulseBurstTab.Title = 'Pulse Burst';
            app.PulseBurstTab.Tag = 'burst';
            app.FrequencyHzLabel = uilabel(app.PulseBurstTab);
            app.FrequencyHzLabel.Text = 'Frequency (Hz):';
            app.BurstIntervalsLabel = uilabel(app.PulseBurstTab);
            app.BurstIntervalsLabel.Text = 'Burst Frequency (Hz):';
            app.BurstBurstFreqEditField = uieditfield(app.PulseBurstTab, 'numeric');
            app.BurstBurstFreqEditField.Limits = [0 Inf];
            app.BurstBurstFreqEditField.Tooltip = {'Burst rate'};
            app.BurstBurstFreqEditField.Value = 3;
            app.PulsesBurstSpinnerLabel = uilabel(app.PulseBurstTab);
            app.PulsesBurstSpinnerLabel.Text = 'Pulses / Burst';
            app.BurstPulseCountSpinner = uispinner(app.PulseBurstTab);
            app.BurstPulseCountSpinner.Limits = [1 Inf];
            app.BurstPulseCountSpinner.ValueDisplayFormat = '%.0f';
            app.BurstPulseCountSpinner.Tooltip = {'Number of pulses in each burst'};
            app.BurstPulseCountSpinner.Value = 4;
            app.BurstFrequencyEditField = uieditfield(app.PulseBurstTab, 'numeric');
            app.BurstFrequencyEditField.Limits = [0 Inf];
            app.BurstFrequencyEditField.Tooltip = {'Pulse rate within bursts'};
            app.BurstFrequencyEditField.Value = 50;
            app.BurstStimDurationEditField = uieditfield(app.PulseBurstTab, 'numeric');
            app.BurstStimDurationEditField.LowerLimitInclusive = 'off';
            app.BurstStimDurationEditField.Limits = [0 Inf];
            app.BurstStimDurationEditField.Tooltip = {'Duration of stimulation'};
            app.BurstStimDurationEditField.Value = 5;
            app.AmplitudemAEditFieldLabel_2 = uilabel(app.PulseBurstTab);
            app.AmplitudemAEditFieldLabel_2.Text = 'Amplitude (mA):';
            app.PulseWidthmsEditFieldLabel_2 = uilabel(app.PulseBurstTab);
            app.PulseWidthmsEditFieldLabel_2.Text = 'Pulse Width (ms):';
            app.BurstPulseWidthEditField = uieditfield(app.PulseBurstTab, 'numeric');
            app.BurstPulseWidthEditField.LowerLimitInclusive = 'off';
            app.BurstPulseWidthEditField.Limits = [0 Inf];
            app.BurstPulseWidthEditField.Tooltip = {'Duration of negative and positive currents.'; '-> Overall pulse duration is twice this value.'};
            app.BurstPulseWidthEditField.Value = 1;
            app.BurstAmplitudeEditField = uieditfield(app.PulseBurstTab, 'numeric');
            app.BurstAmplitudeEditField.LowerLimitInclusive = 'off';
            app.BurstAmplitudeEditField.Limits = [0 Inf];
            app.BurstAmplitudeEditField.Tooltip = {'Current will be first set to -x and then to +x'};
            app.BurstAmplitudeEditField.Value = 6;
            app.StimDurationsLabel_2 = uilabel(app.PulseBurstTab);
            app.StimDurationsLabel_2.Text = 'Stim. Duration (s):';
            app.StimulationDelayEditField = uieditfield(app.RunStimulationPanel, 'numeric');
            app.StimulationDelayEditField.Limits = [0 Inf];
            app.StimulationDelayEditField.Tooltip = {'Delay of stimulation onset.'};
            app.StimulationDelayEditField.Value = 60;
            app.DelaysLabel_2 = uilabel(app.RunStimulationPanel);
            app.DelaysLabel_2.Text = 'Stimulation delay (s)';
            app.ABORTStimButton = uibutton(app.RunStimulationPanel, 'push');
            app.ABORTStimButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTStimButtonPushed, true);
            app.ABORTStimButton.Visible = 'off';
            app.ABORTStimButton.Text = 'ABORT STIMULATION';
            app.BolusTriggercontrolPanel = uipanel(app.StimCtrlUIFigure);
            app.BolusTriggercontrolPanel.Title = 'Bolus & Trigger control';
            app.BOLUSButton = uibutton(app.BolusTriggercontrolPanel, 'push');
            app.BOLUSButton.ButtonPushedFcn = createCallbackFcn(app, @bolus_init, true);
            app.BOLUSButton.Text = 'BOLUS';
            app.sendcustomtriggerButton = uibutton(app.BolusTriggercontrolPanel, 'push');
            app.sendcustomtriggerButton.ButtonPushedFcn = createCallbackFcn(app, @sendCustomTrg, true);
            app.sendcustomtriggerButton.Text = 'send custom trigger:';
            app.NumberAuroraLabel = uilabel(app.BolusTriggercontrolPanel);
            app.NumberAuroraLabel.Text = 'Number (Aurora):';
            app.DurationPhysioMonitorLabel = uilabel(app.BolusTriggercontrolPanel);
            app.DurationPhysioMonitorLabel.Text = 'Duration (PhysioMonitor):';
            app.Label = uilabel(app.BolusTriggercontrolPanel);
            app.Label.Text = '[1-255]';
            app.TrgNumEditField = uieditfield(app.BolusTriggercontrolPanel, 'numeric');
            app.secondsEditFieldLabel = uilabel(app.BolusTriggercontrolPanel);
            app.secondsEditFieldLabel.Text = ' seconds';
            app.TrgDurationEditField = uieditfield(app.BolusTriggercontrolPanel, 'numeric');
            % Show the figure after all components are created
            app.StimCtrlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StimCtrlGUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.StimCtrlUIFigure)

                % Execute the startup function
                runStartupFcn(app, @init_gui)
            else

                % Focus the running singleton app
                figure(runningApp.StimCtrlUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StimCtrlUIFigure)
        end
    end
end